classdef score < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        DisplayMenu                matlab.ui.container.Menu
        DisplaysettingsMenu        matlab.ui.container.Menu
        ROIsMenu                   matlab.ui.container.Menu
        DatasettingsMenu           matlab.ui.container.Menu
        DataMenu                   matlab.ui.container.Menu
        DataPanel                  matlab.ui.container.Panel
        UIDataAxes                 matlab.ui.control.UIAxes
        DataSettingsPanel          matlab.ui.container.Panel
        UIDataTable                matlab.ui.control.Table
        ROisPanel                  matlab.ui.container.Panel
        UIROITable                 matlab.ui.control.Table
        DisplaysettingsPanel       matlab.ui.container.Panel
        FrameSlider                matlab.ui.control.Slider
        FrameSliderLabel           matlab.ui.control.Label
        ZoomSlider                 matlab.ui.control.Slider
        ZoomLabel                  matlab.ui.control.Label
        NewContour                 matlab.ui.control.Button
        ObjectPicker2              matlab.ui.control.ColorPicker
        ColorPicker2Label          matlab.ui.control.Label
        ChannelColorPicker         matlab.ui.control.ColorPicker
        ColorPickerLabel           matlab.ui.control.Label
        HighDisplaySlider          matlab.ui.control.Slider
        HighLabel                  matlab.ui.control.Label
        LowDisplaySlider           matlab.ui.control.Slider
        LowLabel                   matlab.ui.control.Label
        UIDisplayAnnotationsTable  matlab.ui.control.Table
        UIChannelTable             matlab.ui.control.Table
        UIDisplayAxes              matlab.ui.control.UIAxes
        UIImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        content = struct('ROIList',[]); % Description
    end
    
    methods (Access = private) 

       % Toggle the visibility of a panel and update menu status
        function newVisibility = toggleVisibility(~, menuItem)
            if strcmp(menuItem.Checked, 'on')
                menuItem.Checked = 'off';
                newVisibility = 'off';
            else
                menuItem.Checked = 'on';
                newVisibility = 'on';
            end
        end     
        
        function resizeUIImageAxes(app)
         % Position initiale et dimensions maximales
        baseX = 10;  % Coordonnée X de départ
        baseY = 10;  % Coordonnée Y de départ
        maxWidth = app.UIFigure.Position(3) - baseX;  % Largeur maximale
        maxHeight = app.UIFigure.Position(4) - baseY; % Hauteur maximale

        % Ajuster la taille en fonction de la visibilité des panneaux
        if strcmp(app.DisplaysettingsPanel.Visible, 'on') || strcmp(app.ROisPanel.Visible, 'on') || strcmp(app.DataSettingsPanel.Visible, 'on')
            maxWidth = maxWidth - app.DisplaysettingsPanel.Position(3);
            baseX=baseX+app.DisplaysettingsPanel.Position(3);
        end

         if strcmp(app.DataPanel.Visible, 'on')
            maxWidth = maxWidth - app.DataPanel.Position(3)-10;
        end

        % Mettre à jour la position et la taille de UIImageAxes
        app.UIImageAxes.Position = [baseX, baseY, maxWidth, maxHeight];
        end
    end
    
    methods (Access = public)
        function addROI(app,roiobj)
        % Exemple de logique pour ajouter un ROI à l'application
        % (vous pouvez adapter cette partie à votre cas d'utilisation)
        disp(['Adding ROI: ', roiobj.id]);
        % Ajoutez le ROI dans une table, un affichage ou tout autre traitement
        % Par exemple, ajouter à une liste interne
        % app.roiList{end+1} = roiObj;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roiobj)
            % Connecter les callbacks des menus
            % app.DisplaysettingsMenu.MenuSelectedFcn = @app.DisplaysettingsMenuSelected;
            % app.ROIsMenu.MenuSelectedFcn = @app.ROIsMenuSelected;
            % app.DatasettingsMenu.MenuSelectedFcn = @app.DatasettingsMenuSelected;
            % app.DataMenu.MenuSelectedFcn = @app.DataMenuSelected;

           
            % Initialiser l'état des panneaux
            app.DisplaysettingsPanel.Visible = 'off';
            app.ROisPanel.Visible = 'off';
            app.DataSettingsPanel.Visible = 'off';
            app.DataPanel.Visible = 'off';
        end

        % Callback function
        function DisplaysettingsMenuSelected(app, event)
           
        end

        % Callback function
        function ROIsMenuSelected(app, event)
           
        end

        % Callback function
        function DatasettingsMenuSelected(app, event)
           
        end

        % Callback function
        function DataMenuSelected(app, event)
          
        end

        % Callback function
        function DisplaysettingsMenuSelected2(app, event)

        end

        % Menu selected function: ROIsMenu
        function ROIsMenuSelected2(app, event)
             app.ROisPanel.Visible = app.toggleVisibility(app.ROIsMenu);
             app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DatasettingsMenu
        function DatasettingsMenuSelected2(app, event)
             app.DataSettingsPanel.Visible = app.toggleVisibility(app.DatasettingsMenu);
             app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DataMenu
        function DataMenuSelected2(app, event)
              app.DataPanel.Visible = app.toggleVisibility(app.DataMenu);
              app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DisplaysettingsMenu
        function DisplaysettingsMenuSelected3(app, event)
             app.DisplaysettingsPanel.Visible = app.toggleVisibility(app.DisplaysettingsMenu);
             app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.DisplayMenu = uimenu(app.UIFigure);
            app.DisplayMenu.Text = 'Display';
            app.DisplaysettingsMenu = uimenu(app.DisplayMenu);
            app.DisplaysettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaysettingsMenuSelected3, true);
            app.DisplaysettingsMenu.Text = 'Display settings';
            app.ROIsMenu = uimenu(app.DisplayMenu);
            app.ROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIsMenuSelected2, true);
            app.ROIsMenu.Text = 'ROIs';
            app.DatasettingsMenu = uimenu(app.DisplayMenu);
            app.DatasettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasettingsMenuSelected2, true);
            app.DatasettingsMenu.Text = 'Data settings';
            app.DataMenu = uimenu(app.DisplayMenu);
            app.DataMenu.MenuSelectedFcn = createCallbackFcn(app, @DataMenuSelected2, true);
            app.DataMenu.Text = 'Data';
            app.UIImageAxes = uiaxes(app.UIFigure);
            title(app.UIImageAxes, 'ROI #')
            xlabel(app.UIImageAxes, 'X')
            ylabel(app.UIImageAxes, 'Y')
            zlabel(app.UIImageAxes, 'Z')
            app.DisplaysettingsPanel = uipanel(app.UIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.UIDisplayAxes = uiaxes(app.DisplaysettingsPanel);
            title(app.UIDisplayAxes, 'Title')
            xlabel(app.UIDisplayAxes, 'X')
            ylabel(app.UIDisplayAxes, 'Y')
            zlabel(app.UIDisplayAxes, 'Z')
            app.UIChannelTable = uitable(app.DisplaysettingsPanel);
            app.UIChannelTable.ColumnName = {'Display'; 'Name'; 'Levels'; 'RGB'; 'Weight'; 'Auto'};
            app.UIChannelTable.RowName = {};
            app.UIDisplayAnnotationsTable = uitable(app.DisplaysettingsPanel);
            app.UIDisplayAnnotationsTable.ColumnName = {'Display'; 'Name'; 'RGB'; ''};
            app.UIDisplayAnnotationsTable.RowName = {};
            app.LowLabel = uilabel(app.DisplaysettingsPanel);
            app.LowLabel.Text = 'Low';
            app.LowDisplaySlider = uislider(app.DisplaysettingsPanel);
            app.HighLabel = uilabel(app.DisplaysettingsPanel);
            app.HighLabel.Text = 'High';
            app.HighDisplaySlider = uislider(app.DisplaysettingsPanel);
            app.ColorPickerLabel = uilabel(app.DisplaysettingsPanel);
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ChannelColorPicker = uicolorpicker(app.DisplaysettingsPanel);
            app.ColorPicker2Label = uilabel(app.DisplaysettingsPanel);
            app.ColorPicker2Label.Text = 'Color Picker2';
            app.ObjectPicker2 = uicolorpicker(app.DisplaysettingsPanel);
            app.NewContour = uibutton(app.DisplaysettingsPanel, 'push');
            app.NewContour.Text = 'New Contour';
            app.ZoomLabel = uilabel(app.DisplaysettingsPanel);
            app.ZoomLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.DisplaysettingsPanel);
            app.FrameSliderLabel = uilabel(app.DisplaysettingsPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.DisplaysettingsPanel);
            app.ROisPanel = uipanel(app.UIFigure);
            app.ROisPanel.Title = 'ROis';
            app.UIROITable = uitable(app.ROisPanel);
            app.UIROITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIROITable.RowName = {};
            app.DataSettingsPanel = uipanel(app.UIFigure);
            app.DataSettingsPanel.Title = 'Data settings';
            app.UIDataTable = uitable(app.DataSettingsPanel);
            app.UIDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIDataTable.RowName = {};
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.UIDataAxes = uiaxes(app.DataPanel);
            title(app.UIDataAxes, 'Title')
            xlabel(app.UIDataAxes, 'X')
            ylabel(app.UIDataAxes, 'Y')
            zlabel(app.UIDataAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = score(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end